classdef ImageAnalysisHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageAnalysisHubUIFigure  matlab.ui.Figure
        ImageAnalysisHubLabel     matlab.ui.control.Label
        ChooseAnalysisLabel       matlab.ui.control.Label
        PeriodThresholdingButton  matlab.ui.control.Button
        TracesButton              matlab.ui.control.Button
        EventAnalysisButton       matlab.ui.control.Button
        BurstAnalysisButton       matlab.ui.control.Button
        LoadEventDataButton       matlab.ui.control.Button
        BurstPlotsButton          matlab.ui.control.Button
    end


    properties (Access = private)
        %EventDetectionApp
        LoadEventDataApp
        PeriodThresholdingApp
        TracesApp
        EventAnalysisApp
        BurstAnalysisApp
        BurstPlotsApp
    end

    methods (Access = public)
    
%         function updateEventDetectionButton(app)
%             app.EventDetectionButton.Enable = 'on';
%         end
    
        function updateLoadEventDataButton(app)
            app.LoadEventDataButton.Enable = 'on';
        end
    
        function updatePeriodThresholdingButton(app)
            app.PeriodThresholdingButton.Enable = 'on';
        end
    
        function updateTracesButton(app)
            app.TracesButton.Enable = 'on';
        end
    
        function updateEventAnalysisButton(app)
            app.EventAnalysisButton.Enable = 'on';
        end
    
        function updateBurstAnalysisButton(app)
            app.BurstAnalysisButton.Enable = 'on';
        end
    
        function updateBurstPlotsButton(app)
            app.BurstPlotsButton.Enable = 'on';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %updateEventDetectionButton(app);
            updateLoadEventDataButton(app);
            updatePeriodThresholdingButton(app);
            updateTracesButton(app);
            updateEventAnalysisButton(app);
            updateBurstAnalysisButton(app);
            updateBurstPlotsButton(app);
            
            addpath('apps','scripts')
        end

        % Button pushed function: PeriodThresholdingButton
        function PeriodThresholdingButtonPushed(app, event)
            app.PeriodThresholdingButton.Enable = 'off';
            app.PeriodThresholdingApp = PeriodThresholding(app);
        end

        % Button pushed function: TracesButton
        function TracesButtonPushed(app, event)
            app.TracesButton.Enable = 'off';
            app.TracesApp = Traces(app);
        end

        % Button pushed function: EventAnalysisButton
        function EventAnalysisButtonPushed(app, event)
            app.EventAnalysisButton.Enable = 'off';
            app.EventAnalysisApp = EventAnalysis(app);
        end

        % Button pushed function: BurstAnalysisButton
        function BurstAnalysisButtonPushed(app, event)
            app.BurstAnalysisButton.Enable = 'off';
            app.BurstAnalysisApp = BurstAnalysis(app);
        end

        % Close request function: ImageAnalysisHubUIFigure
        function ImageAnalysisHubUIFigureCloseRequest(app, event)

            delete(app.LoadEventDataApp)
            delete(app.PeriodThresholdingApp)
            delete(app.TracesApp)
            delete(app.EventAnalysisApp)
            delete(app.BurstAnalysisApp)
            delete(app.BurstPlotsApp)
            delete(app)
        end

        % Button pushed function: LoadEventDataButton
        function LoadEventDataButtonPushed(app, event)
            app.LoadEventDataButton.Enable = 'off';
            app.LoadEventDataApp = LoadEventData(app);
        end

        % Button pushed function: BurstPlotsButton
        function BurstPlotsButtonPushed(app, event)
            app.BurstPlotsButton.Enable = 'off';
            app.BurstPlotsApp = BurstPlots(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageAnalysisHubUIFigure = uifigure;
            app.ImageAnalysisHubUIFigure.Name = 'Image Analysis Hub';
            app.ImageAnalysisHubUIFigure.CloseRequestFcn = createCallbackFcn(app, @ImageAnalysisHubUIFigureCloseRequest, true);
            app.ImageAnalysisHubLabel = uilabel(app.ImageAnalysisHubUIFigure);
            app.ImageAnalysisHubLabel.Text = 'Image Analysis Hub';
            app.ChooseAnalysisLabel = uilabel(app.ImageAnalysisHubUIFigure);
            app.ChooseAnalysisLabel.Text = 'Choose Analysis:';
            app.PeriodThresholdingButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.PeriodThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @PeriodThresholdingButtonPushed, true);
            app.PeriodThresholdingButton.Text = 'Period Thresholding';
            app.TracesButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.TracesButton.ButtonPushedFcn = createCallbackFcn(app, @TracesButtonPushed, true);
            app.TracesButton.Text = 'Traces';
            app.EventAnalysisButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.EventAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @EventAnalysisButtonPushed, true);
            app.EventAnalysisButton.Text = 'Event Analysis';
            app.BurstAnalysisButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.BurstAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BurstAnalysisButtonPushed, true);
            app.BurstAnalysisButton.Text = 'Burst Analysis';
            app.LoadEventDataButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.LoadEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEventDataButtonPushed, true);
            app.LoadEventDataButton.Text = 'Load Event Data';
            app.BurstPlotsButton = uibutton(app.ImageAnalysisHubUIFigure, 'push');
            app.BurstPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @BurstPlotsButtonPushed, true);
            app.BurstPlotsButton.Text = 'Burst Plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImageAnalysisHub

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageAnalysisHubUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageAnalysisHubUIFigure)
        end
    end
end