classdef BurstAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurstAnalysisUIFigure           matlab.ui.Figure
        ParametersPanel                 matlab.ui.container.Panel
        BurstAnalysisLabel              matlab.ui.control.Label
        LoadEventDataMATfileEditFieldLabel  matlab.ui.control.Label
        LoadEventDataMATfileEditField   matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        AddedSlicesTextAreaLabel        matlab.ui.control.Label
        AddedSlicesTextArea             matlab.ui.control.TextArea
        DetectionMethodEditFieldLabel   matlab.ui.control.Label
        DetectionMethodEditField        matlab.ui.control.EditField
        EventClassesEditFieldLabel      matlab.ui.control.Label
        EventClassesEditField           matlab.ui.control.EditField
        StartTimesEditFieldLabel        matlab.ui.control.Label
        StartTimesEditField             matlab.ui.control.EditField
        EndTimesEditFieldLabel          matlab.ui.control.Label
        EndTimesEditField               matlab.ui.control.EditField
        AnalysisWindowsTable            matlab.ui.control.Table
        NextButton                      matlab.ui.control.Button
        MeasurementsPanel               matlab.ui.container.Panel
        NumberofBurstsCheckBox          matlab.ui.control.CheckBox
        EventsBurstCheckBox             matlab.ui.control.CheckBox
        BurstDurationCheckBox           matlab.ui.control.CheckBox
        BurstFrequencyCheckBox          matlab.ui.control.CheckBox
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        OutputDirectoryEditField        matlab.ui.control.EditField
        BrowseButton_2                  matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        BackButton_2                    matlab.ui.control.Button
        MinimumEventsBurstEditFieldLabel  matlab.ui.control.Label
        MinimumEventsBurstEditField     matlab.ui.control.NumericEditField
        InterburstIntervalCheckBox      matlab.ui.control.CheckBox
        FractionofSingleEventsCheckBox  matlab.ui.control.CheckBox
        TimetoFirstBurstCheckBox        matlab.ui.control.CheckBox
        BurstTimingCheckBox             matlab.ui.control.CheckBox
        ThresholdAlgorithmPanel         matlab.ui.container.Panel
        ThresholdingMethodsButtonGroup  matlab.ui.container.ButtonGroup
        TruncatedGaussianSingleButton   matlab.ui.control.RadioButton
        logTruncatedGaussianSingleButton  matlab.ui.control.RadioButton
        GaussianOnlyIntersectionButton  matlab.ui.control.RadioButton
        logGaussianOnlyIntersectionButton  matlab.ui.control.RadioButton
        GaussianOnlyMinimumButton       matlab.ui.control.RadioButton
        logGaussianOnlyMinimumButton    matlab.ui.control.RadioButton
        GaussianExponentialIntersectionButton  matlab.ui.control.RadioButton
        logGaussianExpExponentialIntersectionButton  matlab.ui.control.RadioButton
        GaussianExponentialMinimumButton  matlab.ui.control.RadioButton
        logGaussianExpExponentialMinimumButton  matlab.ui.control.RadioButton
        BodovaButton                    matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        ManualEditField                 matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PooledButton                    matlab.ui.control.RadioButton
        ConditionLevelButton            matlab.ui.control.RadioButton
        ExperimentLevelButton           matlab.ui.control.RadioButton
        CellLevelButton                 matlab.ui.control.RadioButton
        EditField                       matlab.ui.control.NumericEditField
        PreviewButton                   matlab.ui.control.Button
        PreviewThresholdPooledOnlyLabel  matlab.ui.control.Label
        BackButton                      matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
    end

    properties (Access = public)
        FilePath % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.ThresholdAlgorithmPanel.Visible = 'off';
            app.MeasurementsPanel.Visible = 'off';
            
            if isfolder('3_Burst_Analysis')
                app.OutputDirectoryEditField.Value = fullfile(pwd,'3_Burst_Analysis');
            end
        end

        % Close request function: BurstAnalysisUIFigure
        function BurstAnalysisUIFigureCloseRequest(app, event)
            updateBurstAnalysisButton(app.CallingApp)
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isfolder('1_Loaded_Datasets')
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile('1_Loaded_Datasets', 'Select MAT-file');
            else
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile(pwd, 'Select MAT-file');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadBurstParameters(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.ParametersPanel.Visible = 'off';
            app.ThresholdAlgorithmPanel.Visible = 'on';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.ParametersPanel.Visible = 'on';
            app.ThresholdAlgorithmPanel.Visible = 'off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.ThresholdAlgorithmPanel.Visible = 'off';
            app.MeasurementsPanel.Visible = 'on';
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            EventData = load(fullfile(app.FilePath,app.LoadEventDataMATfileEditField.Value));
            EventData = EventData.EventData;
            app.EditField.Value = previewThreshold(app,EventData);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.OutputDirectoryEditField.Value = uigetdir(pwd, 'Select output directory');
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.ThresholdAlgorithmPanel.Visible = 'on';
            app.MeasurementsPanel.Visible = 'off';
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            if isempty(app.OutputDirectoryEditField.Value)
                app.OutputDirectoryEditField.Value = pwd;
            end
            
            %assignin('base','app',app)
            runBurstAnalysis(app)
        end

        % Selection changed function: ThresholdingMethodsButtonGroup
        function ThresholdingMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingMethodsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Manual')
                app.ManualEditField.Enable = 'on';
            else
                app.ManualEditField.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurstAnalysisUIFigure = uifigure('Visible', 'off');
            app.BurstAnalysisUIFigure.Name = 'Burst Analysis';
            app.BurstAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @BurstAnalysisUIFigureCloseRequest, true);
            app.ParametersPanel = uipanel(app.BurstAnalysisUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.BurstAnalysisLabel = uilabel(app.ParametersPanel);
            app.BurstAnalysisLabel.Text = 'Burst Analysis';
            app.LoadEventDataMATfileEditFieldLabel = uilabel(app.ParametersPanel);
            app.LoadEventDataMATfileEditFieldLabel.Text = 'Load Event Data MAT-file';
            app.LoadEventDataMATfileEditField = uieditfield(app.ParametersPanel, 'text');
            app.BrowseButton = uibutton(app.ParametersPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.ParametersPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddedSlicesTextAreaLabel = uilabel(app.ParametersPanel);
            app.AddedSlicesTextAreaLabel.Text = 'Added Slices';
            app.AddedSlicesTextArea = uitextarea(app.ParametersPanel);
            app.AddedSlicesTextArea.Editable = 'off';
            app.DetectionMethodEditFieldLabel = uilabel(app.ParametersPanel);
            app.DetectionMethodEditFieldLabel.Text = 'Detection Method';
            app.DetectionMethodEditField = uieditfield(app.ParametersPanel, 'text');
            app.DetectionMethodEditField.Editable = 'off';
            app.EventClassesEditFieldLabel = uilabel(app.ParametersPanel);
            app.EventClassesEditFieldLabel.Text = 'Event Classes';
            app.EventClassesEditField = uieditfield(app.ParametersPanel, 'text');
            app.EventClassesEditField.Editable = 'off';
            app.StartTimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.ParametersPanel, 'text');
            app.StartTimesEditField.Editable = 'off';
            app.EndTimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.ParametersPanel, 'text');
            app.EndTimesEditField.Editable = 'off';
            app.AnalysisWindowsTable = uitable(app.ParametersPanel);
            app.AnalysisWindowsTable.ColumnName = {'Window #'; 'Start Time (s)'; 'End Time (s)'};
            app.AnalysisWindowsTable.RowName = {};
            app.NextButton = uibutton(app.ParametersPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.MeasurementsPanel = uipanel(app.BurstAnalysisUIFigure);
            app.MeasurementsPanel.Title = 'Measurements';
            app.NumberofBurstsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.NumberofBurstsCheckBox.Text = 'Number of Bursts';
            app.NumberofBurstsCheckBox.Value = true;
            app.EventsBurstCheckBox = uicheckbox(app.MeasurementsPanel);
            app.EventsBurstCheckBox.Text = 'Events/Burst';
            app.EventsBurstCheckBox.Value = true;
            app.BurstDurationCheckBox = uicheckbox(app.MeasurementsPanel);
            app.BurstDurationCheckBox.Text = 'Burst Duration';
            app.BurstDurationCheckBox.Value = true;
            app.BurstFrequencyCheckBox = uicheckbox(app.MeasurementsPanel);
            app.BurstFrequencyCheckBox.Text = 'Burst Frequency';
            app.BurstFrequencyCheckBox.Value = true;
            app.OutputDirectoryEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.MeasurementsPanel, 'text');
            app.BrowseButton_2 = uibutton(app.MeasurementsPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.RunAnalysisButton = uibutton(app.MeasurementsPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.BackButton_2 = uibutton(app.MeasurementsPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.MinimumEventsBurstEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MinimumEventsBurstEditFieldLabel.Text = 'Minimum Events/Burst';
            app.MinimumEventsBurstEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.MinimumEventsBurstEditField.Limits = [0 Inf];
            app.MinimumEventsBurstEditField.Value = 3;
            app.InterburstIntervalCheckBox = uicheckbox(app.MeasurementsPanel);
            app.InterburstIntervalCheckBox.Text = 'Interburst Interval';
            app.InterburstIntervalCheckBox.Value = true;
            app.FractionofSingleEventsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.FractionofSingleEventsCheckBox.Text = 'Fraction of Single Events';
            app.FractionofSingleEventsCheckBox.Value = true;
            app.TimetoFirstBurstCheckBox = uicheckbox(app.MeasurementsPanel);
            app.TimetoFirstBurstCheckBox.Text = 'Time to First Burst';
            app.TimetoFirstBurstCheckBox.Value = true;
            app.BurstTimingCheckBox = uicheckbox(app.MeasurementsPanel);
            app.BurstTimingCheckBox.Text = 'Burst Timing';
            app.BurstTimingCheckBox.Value = true;
            app.ThresholdAlgorithmPanel = uipanel(app.BurstAnalysisUIFigure);
            app.ThresholdAlgorithmPanel.Title = 'Threshold Algorithm';
            app.ThresholdingMethodsButtonGroup = uibuttongroup(app.ThresholdAlgorithmPanel);
            app.ThresholdingMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingMethodsButtonGroupSelectionChanged, true);
            app.ThresholdingMethodsButtonGroup.Title = 'Thresholding Methods';
            app.TruncatedGaussianSingleButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.TruncatedGaussianSingleButton.Text = 'Truncated Gaussian Single';
            app.TruncatedGaussianSingleButton.Value = true;
            app.logTruncatedGaussianSingleButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logTruncatedGaussianSingleButton.Text = 'log(Truncated Gaussian Single)';
            app.GaussianOnlyIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianOnlyIntersectionButton.Text = 'Gaussian Only (Intersection)';
            app.logGaussianOnlyIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianOnlyIntersectionButton.Text = 'log(Gaussian Only) (Intersection)';
            app.GaussianOnlyMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianOnlyMinimumButton.Text = 'Gaussian Only (Minimum)';
            app.logGaussianOnlyMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianOnlyMinimumButton.Text = 'log(Gaussian Only) (Minimum)';
            app.GaussianExponentialIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianExponentialIntersectionButton.Text = 'Gaussian + Exponential (Intersection)';
            app.logGaussianExpExponentialIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianExpExponentialIntersectionButton.Text = 'log(Gaussian + Exp-Exponential) (Intersection)';
            app.GaussianExponentialMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianExponentialMinimumButton.Text = 'Gaussian + Exponential (Minimum)';
            app.logGaussianExpExponentialMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianExpExponentialMinimumButton.Text = 'log(Gaussian + Exp-Exponential) (Minimum)';
            app.BodovaButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.BodovaButton.Text = 'Bodova';
            app.ManualButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualEditField = uieditfield(app.ThresholdingMethodsButtonGroup, 'numeric');
            app.ManualEditField.Enable = 'off';
            app.ButtonGroup = uibuttongroup(app.ThresholdAlgorithmPanel);
            app.ButtonGroup.Title = 'Button Group';
            app.PooledButton = uiradiobutton(app.ButtonGroup);
            app.PooledButton.Text = 'Pooled';
            app.PooledButton.Value = true;
            app.ConditionLevelButton = uiradiobutton(app.ButtonGroup);
            app.ConditionLevelButton.Text = 'Condition Level';
            app.ExperimentLevelButton = uiradiobutton(app.ButtonGroup);
            app.ExperimentLevelButton.Text = 'Experiment Level';
            app.CellLevelButton = uiradiobutton(app.ButtonGroup);
            app.CellLevelButton.Text = 'Cell Level';
            app.EditField = uieditfield(app.ThresholdAlgorithmPanel, 'numeric');
            app.EditField.Editable = 'off';
            app.PreviewButton = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.PreviewThresholdPooledOnlyLabel = uilabel(app.ThresholdAlgorithmPanel);
            app.PreviewThresholdPooledOnlyLabel.Text = 'Preview Threshold (Pooled Only)';
            app.BackButton = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NextButton_2 = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            % Show the figure after all components are created
            app.BurstAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurstAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BurstAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurstAnalysisUIFigure)
        end
    end
end