classdef BurstPlots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BurstPlotsUIFigure             matlab.ui.Figure
        BurstsLabel                    matlab.ui.control.Label
        LoadEventDataMATfileEditFieldLabel  matlab.ui.control.Label
        LoadEventDataMATfileEditField  matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        MinTimeEditFieldLabel          matlab.ui.control.Label
        MinTimeEditField               matlab.ui.control.NumericEditField
        MinAmplitudeEditFieldLabel     matlab.ui.control.Label
        MinAmplitudeEditField          matlab.ui.control.NumericEditField
        MaxTimeEditFieldLabel          matlab.ui.control.Label
        MaxTimeEditField               matlab.ui.control.NumericEditField
        MaxAmplitudeEditFieldLabel     matlab.ui.control.Label
        MaxAmplitudeEditField          matlab.ui.control.NumericEditField
        UpdateAxesButton               matlab.ui.control.Button
        ResetAxesButton                matlab.ui.control.Button
        CellDropDownLabel              matlab.ui.control.Label
        CellDropDown                   matlab.ui.control.DropDown
        ViewNewCellButton              matlab.ui.control.Button
        PrevButton                     matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Description
        
    end

    properties (Access = public)
        FilePath % Description
        CellCounter
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.CellCounter = 1;
        end

        % Close request function: BurstPlotsUIFigure
        function BurstPlotsUIFigureCloseRequest(app, event)
            updateBurstPlotsButton(app.CallingApp)
            delete(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isfolder('3_Burst_Analysis')
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile('3_Burst_Analysis/MAT-files', 'Select MAT-file');
            else
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile(pwd, 'Select MAT-file');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            loadBurstData(app)
            app.CellCounter = 1;
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: UpdateAxesButton
        function UpdateAxesButtonPushed(app, event)
            app.UIAxes.XLim = [app.MinTimeEditField.Value,app.MaxTimeEditField.Value];
            app.UIAxes.YLim = [app.MinAmplitudeEditField.Value,app.MaxAmplitudeEditField.Value];
        end

        % Button pushed function: ViewNewCellButton
        function ViewNewCellButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNewCellBursts(app)
            app.CellCounter= find(strcmp(app.CellDropDown.Items, app.CellDropDown.Value));
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.CellCounter = app.CellCounter - 1;
            if app.CellCounter < 1
                app.CellCounter = 1;
            end
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNextCellBursts(app)
            app.CellDropDown.Value = app.CellDropDown.Items{app.CellCounter};
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CellCounter = app.CellCounter + 1;
            if app.CellCounter > numel(app.CellDropDown.Items)
                app.CellCounter = numel(app.CellDropDown.Items);
            end
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNextCellBursts(app)
            app.CellDropDown.Value = app.CellDropDown.Items{app.CellCounter};
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNewCellBursts(app)
            app.CellCounter= find(strcmp(app.CellDropDown.Items, app.CellDropDown.Value));
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BurstPlotsUIFigure = uifigure('Visible', 'off');
            app.BurstPlotsUIFigure.Name = 'Burst Plots';
            app.BurstPlotsUIFigure.CloseRequestFcn = createCallbackFcn(app, @BurstPlotsUIFigureCloseRequest, true);
            app.BurstsLabel = uilabel(app.BurstPlotsUIFigure);
            app.BurstsLabel.Text = 'Bursts';
            app.LoadEventDataMATfileEditFieldLabel = uilabel(app.BurstPlotsUIFigure);
            app.LoadEventDataMATfileEditFieldLabel.Text = 'Load Event Data MAT-file';
            app.LoadEventDataMATfileEditField = uieditfield(app.BurstPlotsUIFigure, 'text');
            app.BrowseButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UIAxes = uiaxes(app.BurstPlotsUIFigure);
            title(app.UIAxes, 'No Data Loaded')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.MinTimeEditFieldLabel = uilabel(app.BurstPlotsUIFigure);
            app.MinTimeEditFieldLabel.Text = 'Min Time';
            app.MinTimeEditField = uieditfield(app.BurstPlotsUIFigure, 'numeric');
            app.MinAmplitudeEditFieldLabel = uilabel(app.BurstPlotsUIFigure);
            app.MinAmplitudeEditFieldLabel.Text = 'Min Amplitude';
            app.MinAmplitudeEditField = uieditfield(app.BurstPlotsUIFigure, 'numeric');
            app.MaxTimeEditFieldLabel = uilabel(app.BurstPlotsUIFigure);
            app.MaxTimeEditFieldLabel.Text = 'Max Time';
            app.MaxTimeEditField = uieditfield(app.BurstPlotsUIFigure, 'numeric');
            app.MaxAmplitudeEditFieldLabel = uilabel(app.BurstPlotsUIFigure);
            app.MaxAmplitudeEditFieldLabel.Text = 'Max Amplitude';
            app.MaxAmplitudeEditField = uieditfield(app.BurstPlotsUIFigure, 'numeric');
            app.UpdateAxesButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.UpdateAxesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAxesButtonPushed, true);
            app.UpdateAxesButton.Text = 'Update Axes';
            app.ResetAxesButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.CellDropDownLabel = uilabel(app.BurstPlotsUIFigure);
            app.CellDropDownLabel.Text = 'Cell';
            app.CellDropDown = uidropdown(app.BurstPlotsUIFigure);
            app.CellDropDown.Items = {};
            app.CellDropDown.Value = {};
            app.ViewNewCellButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.ViewNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @ViewNewCellButtonPushed, true);
            app.ViewNewCellButton.Text = 'View New Cell';
            app.PrevButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.BurstPlotsUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.BurstPlotsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurstPlots(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BurstPlotsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BurstPlotsUIFigure)
        end
    end
end