classdef EventAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EventAnalysisUIFigure           matlab.ui.Figure
        ParametersPanel                 matlab.ui.container.Panel
        EventAnalysisLabel              matlab.ui.control.Label
        LoadEventDataMATfileEditFieldLabel  matlab.ui.control.Label
        LoadEventDataMATfileEditField   matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        AddedSlicesTextAreaLabel        matlab.ui.control.Label
        AddedSlicesTextArea             matlab.ui.control.TextArea
        DetectionMethodTextAreaLabel    matlab.ui.control.Label
        DetectionMethodTextArea         matlab.ui.control.TextArea
        EventClassesTextAreaLabel       matlab.ui.control.Label
        EventClassesTextArea            matlab.ui.control.TextArea
        BinTimesTextAreaLabel           matlab.ui.control.Label
        BinTimesTextArea                matlab.ui.control.TextArea
        StartTimesTextAreaLabel         matlab.ui.control.Label
        StartTimesTextArea              matlab.ui.control.TextArea
        AnalysisWindowsTable            matlab.ui.control.Table
        NextButton                      matlab.ui.control.Button
        EndTimesTextAreaLabel           matlab.ui.control.Label
        EndTimesTextArea                matlab.ui.control.TextArea
        ThresholdAlgorithmPanel         matlab.ui.container.Panel
        BackButton_2                    matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        ThresholdLevelButtonGroup       matlab.ui.container.ButtonGroup
        PooledButton                    matlab.ui.control.RadioButton
        ConditionLevelButton            matlab.ui.control.RadioButton
        ExperimentLevelButton           matlab.ui.control.RadioButton
        CellLevelButton                 matlab.ui.control.RadioButton
        ThresholdingMethodsButtonGroup  matlab.ui.container.ButtonGroup
        TruncatedGaussianSingleButton   matlab.ui.control.RadioButton
        logTruncatedGaussianSingleButton  matlab.ui.control.RadioButton
        GaussianOnlyIntersectionButton  matlab.ui.control.RadioButton
        logGaussianOnlyIntersectionButton  matlab.ui.control.RadioButton
        GaussianOnlyMinimumButton       matlab.ui.control.RadioButton
        logGaussianOnlyMinimumButton    matlab.ui.control.RadioButton
        GaussianExponentialIntersectionButton  matlab.ui.control.RadioButton
        logGaussianExpExponentialIntersectionButton  matlab.ui.control.RadioButton
        GaussianExponentialMinimumButton  matlab.ui.control.RadioButton
        logGaussianExpExponentialMinimumButton  matlab.ui.control.RadioButton
        BodovaButton                    matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        ManualEditField                 matlab.ui.control.NumericEditField
        PreviewButton                   matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        PreviewThresholdPooledOnlyLabel  matlab.ui.control.Label
        MeasurementsPanel               matlab.ui.container.Panel
        EventsBinCheckBox               matlab.ui.control.CheckBox
        FrequencyBinCheckBox            matlab.ui.control.CheckBox
        EventTimingCheckBox             matlab.ui.control.CheckBox
        AllPeriodsCheckBox              matlab.ui.control.CheckBox
        EventPeriodsCheckBox            matlab.ui.control.CheckBox
        QuiescentPeriodsCheckBox        matlab.ui.control.CheckBox
        FractionalActiveTimeTimeWindowsCheckBox  matlab.ui.control.CheckBox
        FractionalActiveTimeFirstLastEventCheckBox  matlab.ui.control.CheckBox
        BackButton                      matlab.ui.control.Button
        RunAnalysisButton               matlab.ui.control.Button
        CellswithFewerthan3EventsButtonGroup  matlab.ui.container.ButtonGroup
        IncludeinAveragesButton         matlab.ui.control.RadioButton
        RemovefromAveragesButton        matlab.ui.control.RadioButton
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        OutputDirectoryEditField        matlab.ui.control.EditField
        BrowseButton_3                  matlab.ui.control.Button
        MasterExcelFileEditFieldLabel   matlab.ui.control.Label
        MasterExcelFileEditField        matlab.ui.control.EditField
        BrowseButton_4                  matlab.ui.control.Button
        CreateclusterFuncOutputCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        CallingApp % Description
    end

    properties (Access = public)
        FilePath % Description
        ThresholdFilePath
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.ThresholdAlgorithmPanel.Visible = 'off';
            app.MeasurementsPanel.Visible = 'off';
            
            if isfolder('2_Event_Analysis')
                app.OutputDirectoryEditField.Value = fullfile(pwd,'2_Event_Analysis');
                app.MasterExcelFileEditField.Value = fullfile(pwd,'2_Event_Analysis','0_Master_Parameters_List.xlsx');
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            if isfolder('1_Loaded_Datasets')
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile('1_Loaded_Datasets', 'Select MAT-file');
            else
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile(pwd, 'Select MAT-file');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %try
                loadParameters(app)  
            %catch
            %    msgbox('Wrong data format.', 'Bad Event Data File', 'error')
            %end
        end

        % Close request function: EventAnalysisUIFigure
        function EventAnalysisUIFigureCloseRequest(app, event)
            updateEventAnalysisButton(app.CallingApp)
            delete(app)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.ParametersPanel.Visible = 'off';
            app.ThresholdAlgorithmPanel.Visible = 'on';
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.ParametersPanel.Visible = 'on';
            app.ThresholdAlgorithmPanel.Visible = 'off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            app.ThresholdAlgorithmPanel.Visible = 'off';
            app.MeasurementsPanel.Visible = 'on';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.ThresholdAlgorithmPanel.Visible = 'on';
            app.MeasurementsPanel.Visible = 'off';
        end

        % Callback function
        function BrowseButton_2Pushed(app, event)
            if isfolder('2_Period_Thresholding')
                [app.LoadThresholdResultsEditField.Value,app.ThresholdFilePath] = uigetfile('2_Period_Thresholding', 'Select MAT-file');
            else
                [app.LoadThresholdResultsEditField.Value,app.ThresholdFilePath] = uigetfile(pwd, 'Select MAT-file');
            end
        end

        % Callback function
        function LoadButton_2Pushed(app, event)
            loadThresholds(app)
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            app.OutputDirectoryEditField.Value = uigetdir(pwd, 'Select output directory');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            if isempty(app.OutputDirectoryEditField.Value)
                app.OutputDirectoryEditField.Value = pwd;
            end
            
            %assignin('base','app',app)
            runEventAnalysis(app)
        end

        % Selection changed function: ThresholdingMethodsButtonGroup
        function ThresholdingMethodsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ThresholdingMethodsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Manual')
                app.ManualEditField.Enable = 'on';
            else
                app.ManualEditField.Enable = 'off';
            end
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            app.MasterExcelFileEditField.Value = uigetfile(pwd, 'Select Master Parameters List Excel File');
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            EventData = load(fullfile(app.FilePath,app.LoadEventDataMATfileEditField.Value));
            EventData = EventData.EventData;
            app.EditField_2.Value = previewThreshold(app,EventData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EventAnalysisUIFigure = uifigure('Visible', 'off');
            app.EventAnalysisUIFigure.Name = 'Event Analysis';
            app.EventAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @EventAnalysisUIFigureCloseRequest, true);
            app.ParametersPanel = uipanel(app.EventAnalysisUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.EventAnalysisLabel = uilabel(app.ParametersPanel);
            app.EventAnalysisLabel.Text = 'Event Analysis';
            app.LoadEventDataMATfileEditFieldLabel = uilabel(app.ParametersPanel);
            app.LoadEventDataMATfileEditFieldLabel.Text = 'Load Event Data MAT-file';
            app.LoadEventDataMATfileEditField = uieditfield(app.ParametersPanel, 'text');
            app.BrowseButton = uibutton(app.ParametersPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.ParametersPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AddedSlicesTextAreaLabel = uilabel(app.ParametersPanel);
            app.AddedSlicesTextAreaLabel.Text = 'Added Slices';
            app.AddedSlicesTextArea = uitextarea(app.ParametersPanel);
            app.AddedSlicesTextArea.Editable = 'off';
            app.DetectionMethodTextAreaLabel = uilabel(app.ParametersPanel);
            app.DetectionMethodTextAreaLabel.Text = 'Detection Method';
            app.DetectionMethodTextArea = uitextarea(app.ParametersPanel);
            app.DetectionMethodTextArea.Editable = 'off';
            app.EventClassesTextAreaLabel = uilabel(app.ParametersPanel);
            app.EventClassesTextAreaLabel.Text = 'Event Classes';
            app.EventClassesTextArea = uitextarea(app.ParametersPanel);
            app.EventClassesTextArea.Editable = 'off';
            app.BinTimesTextAreaLabel = uilabel(app.ParametersPanel);
            app.BinTimesTextAreaLabel.Text = 'Bin Time (s)';
            app.BinTimesTextArea = uitextarea(app.ParametersPanel);
            app.BinTimesTextArea.Editable = 'off';
            app.StartTimesTextAreaLabel = uilabel(app.ParametersPanel);
            app.StartTimesTextAreaLabel.Text = 'Start Time (s)';
            app.StartTimesTextArea = uitextarea(app.ParametersPanel);
            app.StartTimesTextArea.Editable = 'off';
            app.AnalysisWindowsTable = uitable(app.ParametersPanel);
            app.AnalysisWindowsTable.ColumnName = {'Window #'; 'Start Time (s)'; 'End Time (s)'};
            app.AnalysisWindowsTable.RowName = {};
            app.AnalysisWindowsTable.ColumnEditable = [false false true];
            app.NextButton = uibutton(app.ParametersPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.EndTimesTextAreaLabel = uilabel(app.ParametersPanel);
            app.EndTimesTextAreaLabel.Text = 'End Time (s)';
            app.EndTimesTextArea = uitextarea(app.ParametersPanel);
            app.EndTimesTextArea.Editable = 'off';
            app.ThresholdAlgorithmPanel = uipanel(app.EventAnalysisUIFigure);
            app.ThresholdAlgorithmPanel.Title = 'Threshold Algorithm';
            app.BackButton_2 = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.NextButton_2 = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.ThresholdLevelButtonGroup = uibuttongroup(app.ThresholdAlgorithmPanel);
            app.ThresholdLevelButtonGroup.Title = 'Threshold Level';
            app.PooledButton = uiradiobutton(app.ThresholdLevelButtonGroup);
            app.PooledButton.Text = 'Pooled';
            app.PooledButton.Value = true;
            app.ConditionLevelButton = uiradiobutton(app.ThresholdLevelButtonGroup);
            app.ConditionLevelButton.Text = 'Condition Level';
            app.ExperimentLevelButton = uiradiobutton(app.ThresholdLevelButtonGroup);
            app.ExperimentLevelButton.Text = 'Experiment Level';
            app.CellLevelButton = uiradiobutton(app.ThresholdLevelButtonGroup);
            app.CellLevelButton.Text = 'Cell Level';
            app.ThresholdingMethodsButtonGroup = uibuttongroup(app.ThresholdAlgorithmPanel);
            app.ThresholdingMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThresholdingMethodsButtonGroupSelectionChanged, true);
            app.ThresholdingMethodsButtonGroup.Title = 'Thresholding Methods';
            app.TruncatedGaussianSingleButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.TruncatedGaussianSingleButton.Text = 'Truncated Gaussian Single';
            app.TruncatedGaussianSingleButton.Value = true;
            app.logTruncatedGaussianSingleButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logTruncatedGaussianSingleButton.Text = 'log(Truncated Gaussian Single)';
            app.GaussianOnlyIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianOnlyIntersectionButton.Text = 'Gaussian Only (Intersection)';
            app.logGaussianOnlyIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianOnlyIntersectionButton.Text = 'log(Gaussian Only) (Intersection)';
            app.GaussianOnlyMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianOnlyMinimumButton.Text = 'Gaussian Only (Minimum)';
            app.logGaussianOnlyMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianOnlyMinimumButton.Text = 'log(Gaussian Only) (Minimum)';
            app.GaussianExponentialIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianExponentialIntersectionButton.Text = 'Gaussian + Exponential (Intersection)';
            app.logGaussianExpExponentialIntersectionButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianExpExponentialIntersectionButton.Text = 'log(Gaussian + Exp-Exponential) (Intersection)';
            app.GaussianExponentialMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.GaussianExponentialMinimumButton.Text = 'Gaussian + Exponential (Minimum)';
            app.logGaussianExpExponentialMinimumButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.logGaussianExpExponentialMinimumButton.Text = 'log(Gaussian + Exp-Exponential) (Minimum)';
            app.BodovaButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.BodovaButton.Text = 'Bodova';
            app.ManualButton = uiradiobutton(app.ThresholdingMethodsButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualEditField = uieditfield(app.ThresholdingMethodsButtonGroup, 'numeric');
            app.ManualEditField.Limits = [0 Inf];
            app.ManualEditField.Enable = 'off';
            app.PreviewButton = uibutton(app.ThresholdAlgorithmPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.EditField_2 = uieditfield(app.ThresholdAlgorithmPanel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.PreviewThresholdPooledOnlyLabel = uilabel(app.ThresholdAlgorithmPanel);
            app.PreviewThresholdPooledOnlyLabel.Text = 'Preview Threshold (Pooled Only)';
            app.MeasurementsPanel = uipanel(app.EventAnalysisUIFigure);
            app.MeasurementsPanel.Title = 'Measurements';
            app.EventsBinCheckBox = uicheckbox(app.MeasurementsPanel);
            app.EventsBinCheckBox.Text = 'Events/Bin';
            app.EventsBinCheckBox.Value = true;
            app.FrequencyBinCheckBox = uicheckbox(app.MeasurementsPanel);
            app.FrequencyBinCheckBox.Text = 'Frequency/Bin';
            app.FrequencyBinCheckBox.Value = true;
            app.EventTimingCheckBox = uicheckbox(app.MeasurementsPanel);
            app.EventTimingCheckBox.Text = 'Event Timing';
            app.EventTimingCheckBox.Value = true;
            app.AllPeriodsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.AllPeriodsCheckBox.Text = 'All Periods';
            app.AllPeriodsCheckBox.Value = true;
            app.EventPeriodsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.EventPeriodsCheckBox.Text = 'Event Periods';
            app.EventPeriodsCheckBox.Value = true;
            app.QuiescentPeriodsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.QuiescentPeriodsCheckBox.Text = 'Quiescent Periods';
            app.QuiescentPeriodsCheckBox.Value = true;
            app.FractionalActiveTimeTimeWindowsCheckBox = uicheckbox(app.MeasurementsPanel);
            app.FractionalActiveTimeTimeWindowsCheckBox.Text = 'Fractional Active-Time (Time Windows)';
            app.FractionalActiveTimeTimeWindowsCheckBox.Value = true;
            app.FractionalActiveTimeFirstLastEventCheckBox = uicheckbox(app.MeasurementsPanel);
            app.FractionalActiveTimeFirstLastEventCheckBox.Text = 'Fractional Active-Time (First-Last Event)';
            app.FractionalActiveTimeFirstLastEventCheckBox.Value = true;
            app.BackButton = uibutton(app.MeasurementsPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.RunAnalysisButton = uibutton(app.MeasurementsPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.CellswithFewerthan3EventsButtonGroup = uibuttongroup(app.MeasurementsPanel);
            app.CellswithFewerthan3EventsButtonGroup.Title = 'Cells with Fewer than 3 Events';
            app.IncludeinAveragesButton = uiradiobutton(app.CellswithFewerthan3EventsButtonGroup);
            app.IncludeinAveragesButton.Text = 'Include in Averages';
            app.IncludeinAveragesButton.Value = true;
            app.RemovefromAveragesButton = uiradiobutton(app.CellswithFewerthan3EventsButtonGroup);
            app.RemovefromAveragesButton.Text = 'Remove from Averages';
            app.OutputDirectoryEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.MeasurementsPanel, 'text');
            app.BrowseButton_3 = uibutton(app.MeasurementsPanel, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.MasterExcelFileEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MasterExcelFileEditFieldLabel.Text = 'Master Excel File';
            app.MasterExcelFileEditField = uieditfield(app.MeasurementsPanel, 'text');
            app.BrowseButton_4 = uibutton(app.MeasurementsPanel, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Text = 'Browse';
            app.CreateclusterFuncOutputCheckBox = uicheckbox(app.MeasurementsPanel);
            app.CreateclusterFuncOutputCheckBox.Text = 'Create clusterFunc Output';
            % Show the figure after all components are created
            app.EventAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EventAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EventAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EventAnalysisUIFigure)
        end
    end
end