classdef LoadEventData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoadEventDataUIFigure          matlab.ui.Figure
        ChooseDataPanel                matlab.ui.container.Panel
        LoadEventDataLabel             matlab.ui.control.Label
        InputDirectoryLabel            matlab.ui.control.Label
        InputDirectoryEditField        matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        EventDetectionAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        minEASEButton                  matlab.ui.control.RadioButton
        letsDetectButton               matlab.ui.control.RadioButton
        SelectButton                   matlab.ui.control.Button
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        DirectoryContentsListBoxLabel  matlab.ui.control.Label
        DirectoryContentsListBox       matlab.ui.control.ListBox
        AddSelectedButton              matlab.ui.control.Button
        AddedSlicesListBoxLabel        matlab.ui.control.Label
        AddedSlicesListBox             matlab.ui.control.ListBox
        RemoveSelectedButton           matlab.ui.control.Button
        BrowseButton_2                 matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        ParametersPanel                matlab.ui.container.Panel
        BinTimesEditFieldLabel         matlab.ui.control.Label
        BinTimesEditField              matlab.ui.control.NumericEditField
        StartTimesEditFieldLabel       matlab.ui.control.Label
        StartTimesEditField            matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel         matlab.ui.control.Label
        EndTimesEditField              matlab.ui.control.NumericEditField
        EventClassesToIncludeminEASEonlyLabel  matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        RemoveButton                   matlab.ui.control.Button
        ClassNumberEditFieldLabel      matlab.ui.control.Label
        ClassNumberEditField           matlab.ui.control.NumericEditField
        AddedClassesListBoxLabel       matlab.ui.control.Label
        AddedClassesListBox            matlab.ui.control.ListBox
        AnalysisWindowsLabel           matlab.ui.control.Label
        AnalysisWindowsTable           matlab.ui.control.Table
        UseAnalysisWindowsCheckBox     matlab.ui.control.CheckBox
        LoadDataButton                 matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp % Description
        Windows
        WindowStarts
        WindowEnds
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            
            if isfolder('0_Detected_Events')
                app.InputDirectoryEditField.Value = fullfile(pwd,'0_Detected_Events');
            else
                app.InputDirectoryEditField.Value = pwd;
            end
            
            if isfolder('1_Loaded_Datasets')
                app.OutputDirectoryEditField.Value = fullfile(pwd,'1_Loaded_Datasets');
            else
                app.OutputDirectoryEditField.Value = pwd;
            end


            app.ParametersPanel.Visible = 'off';
            
            workDir = dir(app.InputDirectoryEditField.Value);
            for i = 1:length(workDir)
                if workDir(i).isdir && ~strcmp(workDir(i).name(1), '.')
                    app.DirectoryContentsListBox.Items = [app.DirectoryContentsListBox.Items, workDir(i).name];
                end
            end
            
            app.Windows = [1;2];
            app.WindowStarts = [0;0];
            app.WindowEnds   = [0;0];
            windowTbl = table(app.Windows,app.WindowStarts,app.WindowEnds);
            app.AnalysisWindowsTable.Data = windowTbl;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.InputDirectoryEditField.Value = uigetdir(app.InputDirectoryEditField.Value, 'Select Input Directory');
            
            workDir = dir(app.InputDirectoryEditField.Value);
            app.DirectoryContentsListBox.Items = cell(0,0);
            for i = 1:length(workDir)
                if workDir(i).isdir && ~strcmp(workDir(i).name(1), '.')
                    app.DirectoryContentsListBox.Items = [app.DirectoryContentsListBox.Items, workDir(i).name];
                end
            end
        end

        % Button pushed function: AddSelectedButton
        function AddSelectedButtonPushed(app, event)
            for i = 1:length(app.DirectoryContentsListBox.Value)
                if isempty(find(strcmp(app.AddedSlicesListBox.Items, app.DirectoryContentsListBox.Value{i})))
                    app.AddedSlicesListBox.Items = [app.AddedSlicesListBox.Items, app.DirectoryContentsListBox.Value{i}];
                end
            end
        end

        % Button pushed function: RemoveSelectedButton
        function RemoveSelectedButtonPushed(app, event)
            nSlices = length(app.AddedSlicesListBox.Value);
            removeSliceIdx = [];
            for i = 1:nSlices
                removeSlice = app.AddedSlicesListBox.Value{i};
                removeSliceIdx = [removeSliceIdx,find(contains(app.AddedSlicesListBox.Items, removeSlice))];     
            end
            if ~isempty(removeSliceIdx)
                app.AddedSlicesListBox.Items(removeSliceIdx) = [];
            end
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            if isempty(app.OutputDirectoryEditField.Value)
                app.OutputDirectoryEditField.Value = pwd;
            end
            
            [EventData, writeEventData] = loadData(app);
            if writeEventData
                fileName = strcat('eventData_',string(datetime('now','Format','d_MMM_y_HH.mm')),'.mat');
                save(strcat(app.OutputDirectoryEditField.Value,'/',fileName),'EventData');
                %msgbox([fileName,' written to ',app.OutputDirectoryEditField.Value,' successfully!'],'Success');
            end
        end

        % Close request function: LoadEventDataUIFigure
        function LoadEventDataUIFigureCloseRequest(app, event)
            updateLoadEventDataButton(app.CallingApp)
            delete(app)     
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            app.OutputDirectoryEditField.Value = uigetdir(app.OutputDirectoryEditField.Value, 'Select Output Directory');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.ParametersPanel.Visible = 'on';
            app.ChooseDataPanel.Visible = 'off';
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.ParametersPanel.Visible = 'off';
            app.ChooseDataPanel.Visible = 'on';
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(find(strcmp(app.AddedClassesListBox.Items, num2str(app.ClassNumberEditField.Value)),1))
                app.AddedClassesListBox.Items = [app.AddedClassesListBox.Items, num2str(app.ClassNumberEditField.Value)];
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            removeClass = app.AddedClassesListBox.Value;
            removeClassIdx = strcmp(app.AddedClassesListBox.Items, removeClass);
            app.AddedClassesListBox.Items(removeClassIdx) = [];
        end

        % Value changed function: UseAnalysisWindowsCheckBox
        function UseAnalysisWindowsCheckBoxValueChanged(app, event)
            value = app.UseAnalysisWindowsCheckBox.Value;
            if value
                app.AnalysisWindowsTable.ColumnEditable = logical([0,1,1]);
            else
                app.AnalysisWindowsTable.ColumnEditable = logical([0,0,0]);
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            if isfolder('0_Detected_Events')
                if app.minEASEButton.Value
                    app.InputDirectoryEditField.Value = fullfile(pwd,'0_Detected_Events','minEASE');
                else
                    app.InputDirectoryEditField.Value = fullfile(pwd,'0_Detected_Events','letsDetect');
                end
                
                app.DirectoryContentsListBox.Items = {};
                workDir = dir(app.InputDirectoryEditField.Value);
                for i = 1:length(workDir)
                    if workDir(i).isdir && ~strcmp(workDir(i).name(1), '.')
                        app.DirectoryContentsListBox.Items = [app.DirectoryContentsListBox.Items, workDir(i).name];
                    end
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoadEventDataUIFigure = uifigure;
            app.LoadEventDataUIFigure.Name = 'Load Event Data';
            app.LoadEventDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadEventDataUIFigureCloseRequest, true);
            app.ChooseDataPanel = uipanel(app.LoadEventDataUIFigure);
            app.ChooseDataPanel.Title = 'Choose Data';
            app.LoadEventDataLabel = uilabel(app.ChooseDataPanel);
            app.LoadEventDataLabel.Text = 'Load Event Data';
            app.InputDirectoryLabel = uilabel(app.ChooseDataPanel);
            app.InputDirectoryLabel.Text = 'Input Directory';
            app.InputDirectoryEditField = uieditfield(app.ChooseDataPanel, 'text');
            app.BrowseButton = uibutton(app.ChooseDataPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EventDetectionAlgorithmButtonGroup = uibuttongroup(app.ChooseDataPanel);
            app.EventDetectionAlgorithmButtonGroup.Title = 'Event Detection Algorithm';
            app.minEASEButton = uiradiobutton(app.EventDetectionAlgorithmButtonGroup);
            app.minEASEButton.Text = 'minEASE';
            app.minEASEButton.Value = true;
            app.letsDetectButton = uiradiobutton(app.EventDetectionAlgorithmButtonGroup);
            app.letsDetectButton.Text = 'letsDetect';
            app.SelectButton = uibutton(app.EventDetectionAlgorithmButtonGroup, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.OutputDirectoryEditFieldLabel = uilabel(app.ChooseDataPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.ChooseDataPanel, 'text');
            app.DirectoryContentsListBoxLabel = uilabel(app.ChooseDataPanel);
            app.DirectoryContentsListBoxLabel.Text = 'Directory Contents';
            app.DirectoryContentsListBox = uilistbox(app.ChooseDataPanel);
            app.DirectoryContentsListBox.Items = {};
            app.DirectoryContentsListBox.Multiselect = 'on';
            app.DirectoryContentsListBox.Value = {};
            app.AddSelectedButton = uibutton(app.ChooseDataPanel, 'push');
            app.AddSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @AddSelectedButtonPushed, true);
            app.AddSelectedButton.Text = 'Add Selected';
            app.AddedSlicesListBoxLabel = uilabel(app.ChooseDataPanel);
            app.AddedSlicesListBoxLabel.Text = 'Added Slices';
            app.AddedSlicesListBox = uilistbox(app.ChooseDataPanel);
            app.AddedSlicesListBox.Items = {};
            app.AddedSlicesListBox.Multiselect = 'on';
            app.AddedSlicesListBox.Value = {};
            app.RemoveSelectedButton = uibutton(app.ChooseDataPanel, 'push');
            app.RemoveSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelectedButtonPushed, true);
            app.RemoveSelectedButton.Text = 'Remove Selected';
            app.BrowseButton_2 = uibutton(app.ChooseDataPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.NextButton = uibutton(app.ChooseDataPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ParametersPanel = uipanel(app.LoadEventDataUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.BinTimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.BinTimesEditFieldLabel.Text = 'Bin Time (s)';
            app.BinTimesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.BinTimesEditField.Limits = [0 Inf];
            app.StartTimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.StartTimesEditField.Limits = [0 Inf];
            app.EndTimesEditFieldLabel = uilabel(app.ParametersPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EventClassesToIncludeminEASEonlyLabel = uilabel(app.ParametersPanel);
            app.EventClassesToIncludeminEASEonlyLabel.Text = 'Event Classes To Include (minEASE only)';
            app.AddButton = uibutton(app.ParametersPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.ParametersPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ClassNumberEditFieldLabel = uilabel(app.ParametersPanel);
            app.ClassNumberEditFieldLabel.Text = 'Class Number';
            app.ClassNumberEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ClassNumberEditField.Limits = [0 Inf];
            app.AddedClassesListBoxLabel = uilabel(app.ParametersPanel);
            app.AddedClassesListBoxLabel.Text = 'Added Classes';
            app.AddedClassesListBox = uilistbox(app.ParametersPanel);
            app.AddedClassesListBox.Items = {'1', '2', '3', '4', '5'};
            app.AddedClassesListBox.Value = '1';
            app.AnalysisWindowsLabel = uilabel(app.ParametersPanel);
            app.AnalysisWindowsLabel.Text = 'Analysis Windows';
            app.AnalysisWindowsTable = uitable(app.ParametersPanel);
            app.AnalysisWindowsTable.ColumnName = {'Window #'; 'Start Time (s)'; 'End Time (s)'};
            app.AnalysisWindowsTable.RowName = {};
            app.AnalysisWindowsTable.ColumnEditable = [false false true];
            app.UseAnalysisWindowsCheckBox = uicheckbox(app.ParametersPanel);
            app.UseAnalysisWindowsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseAnalysisWindowsCheckBoxValueChanged, true);
            app.UseAnalysisWindowsCheckBox.Text = 'Use Analysis Windows';
            app.LoadDataButton = uibutton(app.ParametersPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.BackButton = uibutton(app.ParametersPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LoadEventData(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoadEventDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoadEventDataUIFigure)
        end
    end
end