classdef Traces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TracesUIFigure                 matlab.ui.Figure
        TracesLabel                    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        CellDropDownLabel              matlab.ui.control.Label
        CellDropDown                   matlab.ui.control.DropDown
        ViewNewCellButton              matlab.ui.control.Button
        LoadEventDataMATfileEditFieldLabel  matlab.ui.control.Label
        LoadEventDataMATfileEditField  matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        MinTimeEditFieldLabel          matlab.ui.control.Label
        MinTimeEditField               matlab.ui.control.NumericEditField
        MaxTimeEditFieldLabel          matlab.ui.control.Label
        MaxTimeEditField               matlab.ui.control.NumericEditField
        MinAmplitudeEditFieldLabel     matlab.ui.control.Label
        MinAmplitudeEditField          matlab.ui.control.NumericEditField
        MaxAmplitudeEditFieldLabel     matlab.ui.control.Label
        MaxAmplitudeEditField          matlab.ui.control.NumericEditField
        ResetAxesButton                matlab.ui.control.Button
        UpdateAxesButton               matlab.ui.control.Button
        PrevButton                     matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp
    end

    properties (Access = public)
        FilePath % Description
        CellCounter
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.CellCounter = 1;
        end

        % Close request function: TracesUIFigure
        function TracesUIFigureCloseRequest(app, event)
            updateTracesButton(app.CallingApp)
            delete(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            if isfolder('1_Loaded_Datasets')
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile('1_Loaded_Datasets', 'Select MAT-file');
            else
                [app.LoadEventDataMATfileEditField.Value,app.FilePath] = uigetfile(pwd, 'Select MAT-file');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %try
                app.CellCounter = 1;
                loadTraceData(app)
                
                app.MinTimeEditField.Value = app.UIAxes.XLim(1);
                app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
                app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
                app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
            %catch
            %    msgbox('Wrong data format.', 'Bad Event Data File', 'error')
            %end
        end

        % Button pushed function: ViewNewCellButton
        function ViewNewCellButtonPushed(app, event)
            %try
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                loadNewCell(app)
                app.CellCounter= find(strcmp(app.CellDropDown.Items, app.CellDropDown.Value));
                app.MinTimeEditField.Value = app.UIAxes.XLim(1);
                app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
                app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
                app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
            %catch
            %    msgbox('Wrong data format.', 'Bad Event Data File', 'error')
            %end
        end

        % Button pushed function: UpdateAxesButton
        function UpdateAxesButtonPushed(app, event)
            app.UIAxes.XLim = [app.MinTimeEditField.Value,app.MaxTimeEditField.Value];
            app.UIAxes.YLim = [app.MinAmplitudeEditField.Value,app.MaxAmplitudeEditField.Value];
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNewCell(app)
            app.CellCounter= find(strcmp(app.CellDropDown.Items, app.CellDropDown.Value));
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            app.CellCounter = app.CellCounter - 1;
            if app.CellCounter < 1
                app.CellCounter = 1;
            end
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNextCell(app)
            app.CellDropDown.Value = app.CellDropDown.Items{app.CellCounter};
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.CellCounter = app.CellCounter + 1;
            if app.CellCounter > numel(app.CellDropDown.Items)
                app.CellCounter = numel(app.CellDropDown.Items);
            end
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            loadNextCell(app)
            app.CellDropDown.Value = app.CellDropDown.Items{app.CellCounter};
            app.MinTimeEditField.Value = app.UIAxes.XLim(1);
            app.MaxTimeEditField.Value = app.UIAxes.XLim(2);
            app.MinAmplitudeEditField.Value = app.UIAxes.YLim(1);
            app.MaxAmplitudeEditField.Value = app.UIAxes.YLim(2);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TracesUIFigure = uifigure;
            app.TracesUIFigure.Name = 'Traces';
            app.TracesUIFigure.CloseRequestFcn = createCallbackFcn(app, @TracesUIFigureCloseRequest, true);
            app.TracesLabel = uilabel(app.TracesUIFigure);
            app.TracesLabel.Text = 'Traces';
            app.UIAxes = uiaxes(app.TracesUIFigure);
            title(app.UIAxes, 'No Data Loaded')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            app.CellDropDownLabel = uilabel(app.TracesUIFigure);
            app.CellDropDownLabel.Text = 'Cell';
            app.CellDropDown = uidropdown(app.TracesUIFigure);
            app.CellDropDown.Items = {};
            app.CellDropDown.Value = {};
            app.ViewNewCellButton = uibutton(app.TracesUIFigure, 'push');
            app.ViewNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @ViewNewCellButtonPushed, true);
            app.ViewNewCellButton.Text = 'View New Cell';
            app.LoadEventDataMATfileEditFieldLabel = uilabel(app.TracesUIFigure);
            app.LoadEventDataMATfileEditFieldLabel.Text = 'Load Event Data MAT-file';
            app.LoadEventDataMATfileEditField = uieditfield(app.TracesUIFigure, 'text');
            app.BrowseButton = uibutton(app.TracesUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LoadButton = uibutton(app.TracesUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.MinTimeEditFieldLabel = uilabel(app.TracesUIFigure);
            app.MinTimeEditFieldLabel.Text = 'Min Time';
            app.MinTimeEditField = uieditfield(app.TracesUIFigure, 'numeric');
            app.MaxTimeEditFieldLabel = uilabel(app.TracesUIFigure);
            app.MaxTimeEditFieldLabel.Text = 'Max Time';
            app.MaxTimeEditField = uieditfield(app.TracesUIFigure, 'numeric');
            app.MinAmplitudeEditFieldLabel = uilabel(app.TracesUIFigure);
            app.MinAmplitudeEditFieldLabel.Text = 'Min Amplitude';
            app.MinAmplitudeEditField = uieditfield(app.TracesUIFigure, 'numeric');
            app.MaxAmplitudeEditFieldLabel = uilabel(app.TracesUIFigure);
            app.MaxAmplitudeEditFieldLabel.Text = 'Max Amplitude';
            app.MaxAmplitudeEditField = uieditfield(app.TracesUIFigure, 'numeric');
            app.ResetAxesButton = uibutton(app.TracesUIFigure, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.UpdateAxesButton = uibutton(app.TracesUIFigure, 'push');
            app.UpdateAxesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAxesButtonPushed, true);
            app.UpdateAxesButton.Text = 'Update Axes';
            app.PrevButton = uibutton(app.TracesUIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.TracesUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Traces(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TracesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TracesUIFigure)
        end
    end
end